classdef StabilizerFromRaw_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        StartStabilizationButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        FileList = {} % Description
        ListBoxStrings = {} % Description
        PathSlash = '\';
        TempDirName = 0;
        
        %% Set the parameters for the various functions used inthis script
        % This is a maximum change in mean pixel value between
        % frames before the function tags the frames as blink
        % frames. Leave between 20-30
        BlinkThreshold = 25;  % 25; %(ND)
        
        
        %% for poor quality videos MinimumMeanLevel may have to be lowered should stay above 5 and below 15
        %leave app.ToFilter at 0 it tends to make the cross correlation less accurate
        % This is minimum mean pixel value that a frame has to
        % have for it to be considered for analyses.
        MinimumMeanLevel = 2; % 8; %(ND)
        
        % If the video has luminance gradients or has too much pixel noise
        % then it would be wise to filter the video prior to analyses by setting
        % this flag to 1. If not set it to 0.
        ToFilter = 0;
        
        %% Only need 1 or 2 for app.GausSlowCutOff and leave app.ToRemMeanLum at 1
        %keep app.SmoothSD low (between 2 & 3)
        % Low Frequency cutoff  for the gaussbandfilter.m function
        GausSlowCutOff = 3;  % 2; %(ND)
        
        % The std. deviation of the gaussian smothing filter that is
        % applied by the gaussbandfilter.m
        SmoothSD = 2;        % 3; %(ND)
        
        % If the video has luminance artifacts that has high frequency content
        % set this flag to 1 to use the removemeanlum.m function, otherwise
        % set to 0;
        ToRemMeanLum = 1;    % 0; %(ND)
        
        %% Keep app.SmoothSDForMeanRemoval large (>15 especially for low quality videos)
        % The std. deviation of the smoothing function used by the removemeanlum.m  function.
        SmoothSDForMeanRemoval = 15;
        
        % The number of frame to average toget if you want to remove the influence of
        % the mean frame luminance. If you set it to -1 then the program averages all
        % the frames
        NumFramesToAverage = -1;
        
        % from 0.1   % Maximum Change in ratio between the secondpeak and firstpeak
        % between two frames for the frames before the function tags the frames as "bad".
        PeakRatioDiff = .5;
        
        %% keep MaxMotionThreshold large to get most frames if you find your reference
        %looks bad you can decrease this value.
        % Maximum motion between frames, expressed as a percentage
        % of the frame dimensions, before frames are tagged as "bad".
        MaxMotionThreshold = 0.0512; % 0.3;(ND)
        
        % The step size used when choosing a subset of frames
        % from the frames that are good. This is used by the
        % makereference_framerate.m function.
        % This value is not important since if
        % it doesn't use enough frames the code
        % automatically changes it
        CoarseFrameIncrement = 10;
        
        %% app.SampleRateIncrement should be at 16 which =480Hz. helps make sure each strip has enough data
        % The multiple of the framerate that is used
        % to obtain the sample rate of the ocular motion
        % trace when using the makereference_priorref.m function.
        SampleRateIncrement_PriorRef = 32;
        
        % The multiple of the framerate that is used
        % to obtain the sample rate of the ocular motion
        % trace when using the analysevideo_priorref.m function.
        SampleRateIncrement = 32;
        
        %% High value=more strips included keep above 0.6 below 0.9
        % The threshold that is used to locate the strips that had
        % good correlations during the analysis procedure. The lower
        % the number the more samples are discarded as "bad matches".
        BadSampleThreshold = 0.9; % 0.7;(ND)
        
        %% Always keep MaintainTimeRelationships at 1
        % Certain post analysis questions require the stabilised video
        % to reflect accurate time relationships between frames. However
        % over the course of the analysis we drop frames that can't be
        % analysed accurately. If the user requires accurate time
        % relationships then this flag should be set to one, otherwise
        % set it to 0. When this flag is turned on, dropped frames are
        % replaced by a blank frame in the stabilised video.
        MaintainTimeRelationships = 0;
        
        %% Leave NumLinesPerFullFrame at 512 for raw video
        % The number of pixel lines that would have been present in a video
        % frame if data was collected during the vertical mirror flyback.
        NumLinesPerFullFrame = 512;
        
        % When the eye moves faster than the vertical scan rate black lines are present
        % in the stabilised video. This occurs because no image data was collected at
        % this location. If you find these lines disconcerting, then set this flag to 1,
        % otherwise set to 0. These lines are removed by averaging the image data from
        % the lines above and below the black lines.
        BlackLineFlag = 0;
        
        %% Keep maxsizeinvrement at least at 2 This defines the size of you border. If videos have lots of motion increase to 2.5
        % When creating stabilised movies and frames, physical memory is a big issue.
        % If the maximum motion in the raw video is too high, MATLAB runs out of
        % memory and crashes. To prevent that we have to set a maximum size for the
        % stabilised video and frame. The MaxSizeIncrement sets this limit, as a multiple
        % of the raw frame size. The maximum value for this parameter that we have
        % tested is 2.5. Any image that is outside is set limit is cropped.
        MaxSizeIncrement = 2;
        
        %% Keep SplineFlag at 0
        % When calculating splines during the stabilisation, we could calculate
        % splines for individual frames (SplineFlag = 1), or calculate on spline
        % for the full video (SplineFlag = 0).
        SplineFlag = 0;
        
        
        %% Here is the input structure for analyzing the videos if you find you are getting bad references make changes to app.priorref_inputstruct
        % if there's a bunch of junk in your reference you can decrease bad strip
        % threshold to 0.5 and increase minpercentgoodstripsperframe to 0.6
        
        % DEFAULT:
        % priorref_inputstruct = struct('samplerate',[],'vertsearchzone',55,'stripheight',9,...
        %     'badstripthreshold',0.65,'frameincrement',8,'minpercentofgoodstripsperframe',0.4,...
        %     'numlinesperfullframe',NumLinesPerFullFrame);
        
        % Structure that contains input arguments for the makereference_priorref.m function.
        priorref_inputstruct;

        % Structure that contains input arguments for the analysevideo_priorref.m function.
        analyse_inputstruct;
        
        %% Set the correlation flags for the analyses programs keep these set to [1;1]
        % if you want to use a single reference frame for stablization the second
        % value in these should be set to 0.
        % Array that controls the cross-correlations conducted by makereference_framerate
        CorrelationFlagsFramerate = [1;1];
        % Array that controls the cross-correlations conducted by makereference_priorref
        CorrelationFlagsPriorRef = [1;1];
        % Array that controls the cross-correlations conducted by analysevideo_priorref
        CorrelationFlagsAnalyse  = [1;1];
        % For all of the previous correlation flag array, the first element if set to 1 forces the programs to return the
        % shift with sub-pixel accuracy, while the second force the programs to multiply the test matrix with a raised cosine
        % window prior to the cross-correlation
        
        % Array with the analysis flags used by the analysevideo_priorref.m function.
        % Note at moment have not implemented the correction factor, so do not set
        % the second flag in the above array to 1, until otherwise informed.
        AnalyseProgramFlags = [0 0];
        
        %% Set the feedback options for the various functions used inthis script
        % leave these at 0 unless you need to troubleshoot something. FOr
        % troubleshooting it can be helpful to set app.AnalyVerbosity=[1 0 0 0] but it
        % can get very annoying
        % Set to 1 if you want feedback from the getblinkframes.m function, otherwise set to 0.
        BlinkVerbosity = 1;
        
        % Set to 1 if you want feedback from the removemeanlum.m function, otherwise set to 0.
        MeanLumVerbosity = 0;
        
        % Set to 1 if you want feedback from the getbadframes.m function, otherwise set to 0.
        BadFrameVerbosity = 1;
        
        % The verbose array used by the makereference_framerate.m function.
        CoarseRefVerbosity = [0 0];
        
        % The verbose array used by the makereference_priorref.m function.
        FineRefVerbosity = [0 0 0 0];
        
        % The verbose array used by the analysevideo_priorref.m function.
        AnalyVerbosity = [0 0 0 0];
        
        % Set to 1 if you want feedback from the makestabilizedvideo.m function, otherwise set to 0.
        StabVerbosity = 0;
        
        
        % ToMakeMontage = lower(questdlg('Do you want to make a montage of the individual stablised frames',...
        %     'Montage Query','Yes','No','Yes'));
        % ToMakeMontage = strcmp(app.ToMakeMontage(1),'y');
        % Currently the make montage program is a bit buggy - better not to use until otherwise informed
        ToMakeMontage = 0;
        
        % Is global reference frame set
        GlobalReference = 0;
        
        ReferenceImage = [];
    end
    
    methods (Access = private)
        
        function CheckAndGetFileNameFromDirectory(app, fileIndex, dirStucture, dirName)
            if ~(dirStucture(fileIndex).isdir)
                tempname = dirStucture(fileIndex).name;
                fileextension = tempname(end-2:end);
                if strcmpi(fileextension,'AVI')
                    % for excluding already stabilized videos from the list (extension = Bonn Lab)
                    if ~strcmp(tempname(end-13:end),'stabilized.avi')
                        app.FileList{end + 1} = strcat(dirName, app.PathSlash, tempname);
                        app.ListBoxStrings{end + 1} = tempname;
                    end
                end
            end
        end
        
        function ChooseReferenceFrameManualy(app)
            
            SelectionOn = true;
            while SelectionOn
                app.GlobalReference = 1;
                
                QD2_answer = questdlg('Select Reference Frame from video or load image?','Question Dialog 2','Video','Image','Video');
                if strcmp(QD2_answer,'Video')
                    % select video from list
                    selection2 = listdlg('ListString',app.ListBoxStrings,'InitialValue',[],'Name','File Select','SelectionMode','single',...
                        'PromptString','Please select video for global reference');
                    
                    % select frame from choosen video
                    VObj1 = VideoReader(app.FileList{selection2});
                    
                    screensize = get(0,'ScreenSize');
                    DispFramesFig = figure('Position',[screensize(3)/2-800 screensize(4)/2-600 1600 1200]);
                    Frames = 1:1:300;
                    corrValue = 0;
                    for ax = 1+corrValue:6+corrValue
                        subplot(2,3,ax-corrValue), imshow(double(read(VObj1,Frames(ax)))./255);
                        axis off, box off
                        title(sprintf('Frame # %02.0f',Frames(ax)))
                        Names{ax-corrValue} = num2str(Frames(ax));
                    end
                    selection3 = listdlg('ListString',Names,'InitialValue',[],'Name','File Select','SelectionMode','single',...
                        'PromptString','Please select frame number to set global reference frame');
                    close(DispFramesFig);
                    
                    PreRefIm = double(read(VObj1,Frames(selection3+corrValue)));
                    PreRefIm(PreRefIm==1)=0;
                    SyPRI = size(PreRefIm,1);
                    % SxPRI = size(PreRefIm,2);
                    % crop image to central 512x512
                    if SyPRI~=712
                        if SyPRI>712
                            % find central part of image
                            HorizontalBorder = find(mean(PreRefIm,1)<10);
                            VerticalBorder = find(mean(PreRefIm,2)<10);
                            
                            HBPixel = find(diff(HorizontalBorder)>1);
                            HorRange = max(HorizontalBorder(HBPixel+1)-HorizontalBorder(HBPixel));
                            HCenter = round(HorRange/2)+HorizontalBorder(HBPixel);
                            
                            VBPixel = find(diff(VerticalBorder)>1);
                            VerRange = max(VerticalBorder(VBPixel+1)-VerticalBorder(VBPixel));
                            VCenter = round(VerRange/2)+VerticalBorder(VBPixel);
                            
                            %crop
                            app.ReferenceImage = PreRefIm(VCenter-245:VCenter+266,HCenter-255:HCenter+256);  % test with smaller cutout
                        end
                    elseif size(PreRefIm,1)==712
                        app.ReferenceImage = PreRefIm;
                    end
                    
                else
                    ImageSelection = true;
                    while ImageSelection
                        % get path to image
                        [FName,PName,~] = uigetfile([app.TempDirName '\*.tif;*.tiff;*.png;*.jpg;*.jpeg']);
                        
                        % load image
                        if isnumeric(FName)
                            error('No image for global reference selected')
                        else
                            PreRefIm = double(imread([PName FName]));
                            %                     PreRefIm = imread([PName FName]);
                        end
                        
                        SyPRI = size(PreRefIm,1);
                        % SxPRI = size(PreRefIm,2);
                        % crop image to central 512x512
                        if SyPRI~=712
                            if SyPRI>712
                                % find central part of image
                                HorizontalBorder = find(mean(PreRefIm,1)<0.005);
                                VerticalBorder = find(mean(PreRefIm,2)<0.005);
                                
                                HorBorVal = max(diff(HorizontalBorder));
                                HBPixel = find(diff(HorizontalBorder) == HorBorVal);
                                if isempty(HBPixel) || sum(diff(HorizontalBorder)) == length(HBPixel)
                                    HCenter = size(PreRefIm,2)/2;
                                else
                                    HorRange = max(HorizontalBorder(HBPixel+1)-HorizontalBorder(HBPixel));
                                    HCenter = round(HorRange/2)+HorizontalBorder(HBPixel);
                                end
                                
                                VerBorVal = max(diff(VerticalBorder));
                                VBPixel = find(diff(VerticalBorder) == VerBorVal);
                                if isempty(VBPixel) || sum(diff(VerticalBorder)) == length(VBPixel)
                                    VCenter = size(PreRefIm,1)/2;
                                else
                                    VerRange = max(VerticalBorder(VBPixel+1)-VerticalBorder(VBPixel));
                                    VCenter = round(VerRange/2)+VerticalBorder(VBPixel);
                                end
                                
                                %crop
                                app.ReferenceImage = PreRefIm(VCenter-255:VCenter+256,HCenter-255:HCenter+256);
                                ImageSelection = false;
                            else
                                print('Selected Image is too small (min size 512 pixel)')
                            end
                        elseif size(PreRefIm,1)==712
                            app.ReferenceImage = PreRefIm;
                        end
                    end
                end
                
                app.ReferenceImage = PreRefIm;
                Fig1 = figure;
                imshow(app.ReferenceImage./255)
                Answer = questdlg('Reference Frame Okay?','Proceed?','Okay','Change Selection','Okay');
                close(Fig1);
                
                if strcmp(Answer,'Okay')
                    SelectionOn = false;
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartStabilizationButton
        function StartStabilizationButtonPushed(app, event)
            %% Stabilize from raw video
            % (c) 2009 SBStevenson@uh.edu and GKR
            % Editted by bethan@berkeley.edu
            % Parameters of interest start on line 100
            % This script creates a stabilized version of multiple raw AVI format videos.
            % It is designed to work with AOSLO retinal images and will not work very
            % well with conventional SLO images.
            % This script is essentially a macro that calls a series of functions to
            % carry out the analysis steps. These functions should be in
            % ~\MatlabXXXX\toolbox\AOSLO\
            
            % reset randomizer engine
            rng(sum(100 * clock), 'v5uniform');
            rng(sum(100 * clock),'v5normal');
            
            % get current directory
            currentdir = cd;
            % currentdir = 'D:\Video_Folder';
            % currentdir = 'E:\ausgelagerte_Studiendaten';
            % currentdir = 'Z:\Bonn AOSLO\AOSLO\BAK8044R\2019_09_19_15_03_06_AOSLO';
            
            % get current sceensize
            screensize = get(0,'ScreenSize');
            % get proper path slash according to your OS
            if ispc
                app.PathSlash = '\';
            else
                app.PathSlash = '/';
            end
            
            videosnotanalysed = {};
            thrownexceptions = {};
            app.TempDirName = 0;
            
            
            % Structure that contains input arguments for the makereference_priorref.m function.
            app.priorref_inputstruct = struct('samplerate',[],'vertsearchzone',50,'stripheight',9,...
                'badstripthreshold',0.85,'frameincrement',10,'minpercentofgoodstripsperframe',0.4,...
                'numlinesperfullframe',app.NumLinesPerFullFrame);

            % Structure that contains input arguments for the analysevideo_priorref.m function.
            app.analyse_inputstruct = struct('samplerate',[],'vertsearchzone',50,'horisearchzone',[],...
                'startframe',1,'endframe',-1,'stripheight',9,'badstripthreshold',0.85,'minpercentofgoodstripsperframe',0.4,...
                'numlinesperfullframe',app.NumLinesPerFullFrame);
            
            
            %------------------------------------------------------------------------------
            % Get info from the user regarding the directories where the videos are
            % present and then load the video names into a .
            
            prompt={'How many directories are your videos in?'};
            name = 'Directory Query';
            numlines = 1;
            defaultanswer = {'1'};
            
            answer = inputdlg(prompt,name,numlines,defaultanswer);
            if isempty(answer)
                disp('You need to supply the number of directories where you have placed videos,Exiting...');
                return;
            end
            numdirectories = str2double(answer{1});
            
            directorynames = cell(numdirectories,1);
            app.ListBoxStrings = {};
            app.FileList = {};
            
            prevdir = currentdir;
            for directorycounter = 1:numdirectories
                tempdirname = uigetdir(prevdir,'Please choose a single folder with video files');
                if tempdirname == 0
                    disp('You pressed cancel instead of choosing a directory');
                    warning('Continuing...');
                    continue
                end
                directorynames{directorycounter} = tempdirname;
                dirstucture = dir(tempdirname);
                files_in_dir = length(dirstucture);
                arrayfun(@(a) app.CheckAndGetFileNameFromDirectory(a, dirstucture, tempdirname), 1:files_in_dir);
                prevdir = tempdirname;
                cd(tempdirname);
            end
            app.TempDirName = tempdirname;
            
            % If the directory has no video, exit
            if isempty(app.ListBoxStrings)
                error('No AVI videos in selected folders, exiting....');
            end
            
            selection = listdlg('ListString',app.ListBoxStrings,'InitialValue',[],'Name',...
                'File Select','PromptString','Please select videos to stabilize');
            
            % If the user does not choose any video, exit
            if isempty(selection)
                disp('You have not selected any video, Exiting...');
                return;
            end
            numfiletoanalyse = length(selection);
            
            %------------------------------------------------------------------------------
            % Get the image format in which to save the stabilised image from the user
            formatofstabframe = questdlg('In what image format do you want to save the stabilized frame',...
                'Image Format','JPEG','TIFF','GIF','JPEG');
            formatofstabframe = strcat('.',lower(formatofstabframe));
            
            if app.ToMakeMontage
                allstabframes_unfiltered = {};
                allstabframes = {};
            end
            
            cd(currentdir);
            
            %------------------------------------------------------------------------------
            %% Don't touch anything past here it may screw up the progress bar which will cause the program to crash.
            
            % Show interface with progressbar
            TotalNumOfFramesAnalysed = 0;
            
            switch (app.ToFilter + app.ToRemMeanLum)
                case 0
                    StringsToShow = {'Getting Blink Frames';'Getting Bad Frames';...
                        'Making the Coarse Reference Frame';...
                        'Making the Fine Reference Frame';'Analysing Video';...
                        'Making the Stabilised Frame and Video'};
                case 1
                    if app.ToFilter
                        StringsToShow = {'Getting Blink Frames';'Filtering Video';...
                            'Getting Bad Frames';'Making the Coarse Reference Frame';...
                            'Making the Fine Reference Frame';'Analysing Video';...
                            'Making the Stabilised Frame and Video'};
                    else
                        StringsToShow = {'Getting Blink Frames';'Removing Mean Luminance';...
                            'Getting Bad Frames';'Making the Coarse Reference Frame';...
                            'Making the Fine Rerence Frame';'Analysing Video';...
                            'Making the Stabilised Frame and Video'};
                    end
                case 2
                    StringsToShow = {'Getting Blink Frames';'Filtering Video';...
                        'Removing Mean Luminance';'Getting Bad Frames';...
                        'Making the Coarse Reference Frame';...
                        'Making the Fine Reference Frame';'Analysing Video';...
                        'Making the Stabilised Frame and Video'};
            end
            
            numStringsToShow = length(StringsToShow);
            texthandles = zeros(numStringsToShow,1);
            startindex = 1 - ((1 - (numStringsToShow / 10)) / 2);
            endindex = startindex - ((numStringsToShow - 1) / 10);
            indicestoputtext = (startindex:-0.1:endindex);
            
            analysesprogstring = ['Progress of Analyses: 0/',num2str(numfiletoanalyse),' Files Done'];
            userfeedbackfig = figure;
            % oldfigposition = get(userfeedbackfig,'Position');
            newfigposition = [1 1 round(screensize(3) / 3) round(2 * screensize(4) / 3)];
            set(userfeedbackfig,'Position',newfigposition,'Toolbar','none','Name',...
                analysesprogstring,'Units','Normalized');
            axes('Position',[0 0 1 1],'Visible','off','Units','Normalized');
            for textcounter = 1:numStringsToShow
                texthandles(textcounter) = text(0.1, indicestoputtext(textcounter), StringsToShow{textcounter});
            end
            
            tic
            processprog = waitbar(0,'Processing Videos');
            oldposition = get(processprog,'Position');
            newstartindex = round(oldposition(1) - (oldposition(3) / 2));
            newposition = [newstartindex (oldposition(4) + 20) ...
                oldposition(3) oldposition(4)];
            set(processprog,'Position',newposition);
            
            
            %------------------------------------------------------------------------------
            % Set defaults to reference frame variables
            app.GlobalReference = 0;
            app.ReferenceImage = [];
            
            % Ask to choose reference frame manualy.
            % If "NO", then reference frame will be choosen automaticaly
            % for each video file
            QD1_answer = questdlg('Do you want to set a global reference frame?','Question Dialog 1','Yes','No','Yes');
            if strcmp(QD1_answer,'Yes')
                ChooseReferenceFrameManualy(app);
            end
            
            
            Files2Delete = 0;
            % for each video file
            for filecounter = 1:numfiletoanalyse
                try
                    % Update interface
                    currenttexthandleindex = 1;
                    if filecounter > 1
                        set(texthandles(end), 'FontWeight', 'Normal');
                    end
                    
                    % Get video for analysing
                    videotoanalyse = app.FileList{selection(filecounter)};
                    % Read video
                    currentvideoinfo = VideoReader(videotoanalyse);
                    
                    % Get video parameters
                    % frameheight = currentvideoinfo.Height;
                    framewidth = currentvideoinfo.Width;
                    framerate = round(currentvideoinfo.FrameRate);
                    numberofframes = round(currentvideoinfo.FrameRate*currentvideoinfo.Duration);
                    
                    % Update total number of analysed videos
                    TotalNumOfFramesAnalysed = TotalNumOfFramesAnalysed + numberofframes;
                    
                    % Update inputstructs for analysing videos
                    app.priorref_inputstruct.samplerate = round(framerate * app.SampleRateIncrement_PriorRef);
                    app.analyse_inputstruct.samplerate = round(framerate * app.SampleRateIncrement);
                    app.analyse_inputstruct.horisearchzone = (3 * framewidth) / 4;
                    
                    % Get filename for blink frames
                    blinkfilename = strcat(videotoanalyse(1:end - 4),'_blinkframes.mat');
                    
                    % Get name of filtered video, if necessary
                    if app.ToFilter
                        filteredname = strcat(videotoanalyse(1:end - 4),'_bandfilt.avi');
                    else
                        filteredname = videotoanalyse;
                    end
                    
                    % Get name of video file without luminance artifacts
                    if app.ToRemMeanLum
                        finalname =  strcat(filteredname(1:end-4),'_meanrem.avi');
                    else
                        finalname = filteredname;
                    end
                    
                    stabimagename_noext = videotoanalyse(1:end - 4);
                    
                    % Update interface
                    set(texthandles(currenttexthandleindex),'FontWeight','Bold');
                    currenttexthandleindex = currenttexthandleindex + 1;
                    
                    % Get blink frames
                    blinkframes = getblinkframes(videotoanalyse, app.BlinkThreshold,app.MinimumMeanLevel,app.BlinkVerbosity);
                    
                    % Apply filter to video, if necessary
                    if app.ToFilter
                        set(texthandles(currenttexthandleindex - 1),'FontWeight','Normal');
                        set(texthandles(currenttexthandleindex),'FontWeight','Bold');
                        currenttexthandleindex = currenttexthandleindex + 1;
                        gaussbandfilter(videotoanalyse, app.GausSlowCutOff, app.SmoothSD);
                    end
                    
                    % Remove luminance artifacts from video, if necessary
                    if app.ToRemMeanLum
                        set(texthandles(currenttexthandleindex - 1),'FontWeight','Normal');
                        set(texthandles(currenttexthandleindex),'FontWeight','Bold');
                        currenttexthandleindex = currenttexthandleindex + 1;
                        removemeanlum(filteredname,app.SmoothSDForMeanRemoval,app.NumFramesToAverage,app.MeanLumVerbosity);
                    end
                    
                    % Get "good" frames. "Good" - with good correlation
                    % with the frame immediately preceding it. For more
                    % info - check the doc to the function getbadframes.m
                    set(texthandles(currenttexthandleindex - 1),'FontWeight','Normal');
                    set(texthandles(currenttexthandleindex),'FontWeight','Bold');
                    currenttexthandleindex = currenttexthandleindex + 1;
                    [goodframesegmentinfo,largemovementframes] = getbadframes(finalname,blinkfilename,...
                        app.PeakRatioDiff, app.MaxMotionThreshold, app.BadFrameVerbosity);
                    
                    % Get coarse reference frame for the video.
                    set(texthandles(currenttexthandleindex - 1),'FontWeight','Normal');
                    set(texthandles(currenttexthandleindex),'FontWeight','Bold');
                    currenttexthandleindex = currenttexthandleindex + 1;
                    if app.GlobalReference
                        makeReferenceFrameRate_mode = 'global';
                    else
                        makeReferenceFrameRate_mode = 'individual';
                    end
                    [coarsereffilename, coarsereferimage] = makereference_framerate_ND(finalname,...
                        blinkfilename, app.CoarseFrameIncrement, app.BadSampleThreshold,...
                        app.CorrelationFlagsFramerate, app.CoarseRefVerbosity, makeReferenceFrameRate_mode, app.ReferenceImage);
                    
                    %         figure,imshow(coarsereferimage./255)
                    
                    % Get fine reference frame for the video.
                    set(texthandles(currenttexthandleindex - 1),'FontWeight','Normal');
                    set(texthandles(currenttexthandleindex),'FontWeight','Bold');
                    currenttexthandleindex = currenttexthandleindex + 1;
                    if app.GlobalReference
                        makeReferencePriorRef_mode = 'GlobalReference';
                    else
                        makeReferencePriorRef_mode = 'referenceimage';
                    end
                    [finereffilename, finerefimage] = makereference_priorref_ND(finalname,...
                        coarsereffilename,blinkfilename, makeReferencePriorRef_mode, app.priorref_inputstruct,app.CorrelationFlagsPriorRef,...
                        app.FineRefVerbosity);
                    
                    %         figure,imshow(finerefimage./255)
                    
                    % Analysing current video
                    set(texthandles(currenttexthandleindex - 1),'FontWeight','Normal');
                    set(texthandles(currenttexthandleindex),'FontWeight','Bold');
                    currenttexthandleindex = currenttexthandleindex + 1;
                    if app.GlobalReference
                        analysingVideo_mode = 'referenceimage_prior';
                    else
                        analysingVideo_mode = 'referenceimage';
                    end
                    analyseddatafilename = analysevideo_priorref(finalname, finereffilename, blinkfilename,...
                        analysingVideo_mode, app.analyse_inputstruct, app.AnalyseProgramFlags,app.CorrelationFlagsAnalyse , [0,0,0,0]);
                    
                    % Load analysed data
                    load(analyseddatafilename,'analysedframes','frameshifts_strips_spline','peakratios_strips',...
                        'stripidx','referenceimage');
                    app.ReferenceImage = referenceimage;
                    
                    % Make stabilized video frame
                    set(texthandles(currenttexthandleindex - 1),'FontWeight','Normal');
                    set(texthandles(currenttexthandleindex),'FontWeight','Bold');
                    [~, stabilizedmatrix, stabilizedmatrix_full] =...
                        makestabilizedvideoandframe_ND(videotoanalyse, analysedframes,...
                        frameshifts_strips_spline, peakratios_strips, stripidx, app.BadSampleThreshold,...
                        app.MaintainTimeRelationships, app.NumLinesPerFullFrame, app.BlackLineFlag, app.MaxSizeIncrement,...
                        app.SplineFlag, app.StabVerbosity);
                    
                    stabilizedframe = stabilizedmatrix(:,:,3);
                    
                    if app.ToMakeMontage
                        allstabframes_unfiltered{end + 1} = stabilizedmatrix(:,:,3);
                        allstabframes{end + 1} = app.ReferenceImage;
                    end
                    
                    % Save analysed data
                    save(analyseddatafilename,'blinkframes','goodframesegmentinfo','largemovementframes',...
                        'coarsereferimage','finerefimage','stabilizedframe','stabilizedmatrix','stabilizedmatrix_full','-append');
                    
                    Files2Delete = Files2Delete+1;
                    AllAnalyseFiles{Files2Delete} = analyseddatafilename;
                    
                    switch formatofstabframe
                        case '.jpeg'
                            stabimagename = strcat(stabimagename_noext,formatofstabframe);
                            imwrite(stabilizedmatrix(:,:,3) / 256,stabimagename,'Quality',100);
                        case '.tiff'
                            stabimagename = strcat(stabimagename_noext,'.tiff');
                            imwrite(stabilizedmatrix(:,:,3) / 256,stabimagename,'Compression','none');
                        case '.gif'
                            stabimagename = strcat(stabimagename_noext,'.gif');
                            imwrite(stabilizedmatrix(:,:,3) / 256,stabimagename);
                    end
                    
                    prog = filecounter / numfiletoanalyse;
                    waitbar(prog,processprog);
                    
                    analysesprogstring = ['Progress of Analyses: ', num2str(filecounter),'/',num2str(numfiletoanalyse),' Files Done'];
                    set(userfeedbackfig,'Name',analysesprogstring);
                catch exception_object
                    disp(exception_object.identifier);
                    disp(videotoanalyse);
                    videosnotanalysed{end + 1} = videotoanalyse;
                    thrownexceptions{end + 1} = exception_object;
                    
                    prog = filecounter / numfiletoanalyse;
                    waitbar(prog,processprog);
                    
                    analysesprogstring = ['Progress of Analyses: ', num2str(filecounter),'/',num2str(numfiletoanalyse),' Files Done'];
                    set(userfeedbackfig,'Name',analysesprogstring);
                    continue;
                end
            end
            
            timeelapsed = toc;
            close(processprog);
            close(userfeedbackfig);
            
            fprintf('Total time elapsed %03.1f minutes\n', timeelapsed/60);
            fprintf('Average time per video %03.1f seconds\n', timeelapsed / numfiletoanalyse);
            fprintf('Average time per frame %03.1f seconds\n', timeelapsed / TotalNumOfFramesAnalysed);
            
            % Clean temp files if necessary
            if Files2Delete~=0
                DeleteAnaFiles = questdlg('Delete all .mat files from Analysis?','Final Clean Up','Yes: delete','No: keep them','Yes: delete');
                if strcmp(DeleteAnaFiles,'Yes: delete')
                    for ax = 1:Files2Delete
                        delete(AllAnalyseFiles{ax})
                    end
                end
            end
            
            % Make montage is necessary
            if app.ToMakeMontage
                getmontageparameters(allstabframes,[],3,0.6,'montagedata.mat',0);
                montageimage = makemontage('montagedata.mat',allstabframes,allstabframes_unfiltered,0.6,0);
                randstring = num2str(min(ceil(rand(1) * 10000),9999));
                montagestring = ['montagedimage_',randstring];
                
                switch formatofstabframe
                    case '.jpeg'
                        stabimagename = strcat(montagestring,formatofstabframe);
                        imwrite(montageimage / 256,stabimagename,'Quality',100);
                    case '.tiff'
                        stabimagename = strcat(montagestring,'.tiff');
                        imwrite(montageimage / 256,stabimagename,'Compression','none');
                    case '.gif'
                        stabimagename = strcat(montagestring,'.gif');
                        imwrite(montageimage / 256,stabimagename);
                end
            end
            
            % Show occured errors
            if ~isempty(videosnotanalysed)
                disp('There were errors in the analyses');
                disp('Files That Had Errors: ');
                for errorcounter = 1:length(videosnotanalysed)
                    currentexception = thrownexceptions{errorcounter};
                    disp(videosnotanalysed{errorcounter});
                    disp(currentexception.identifier);
                    disp('Matfile throwing the exception: ');
                    disp(currentexception.stack(1).file);
                    disp('Line of Error: ');
                    disp(currentexception.stack(1).line);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartStabilizationButton = uibutton(app.UIFigure, 'push');
            app.StartStabilizationButton.ButtonPushedFcn = createCallbackFcn(app, @StartStabilizationButtonPushed, true);
            app.StartStabilizationButton.Text = 'Start Stabilization';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StabilizerFromRaw_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end